classdef Imgproc_Demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UIAxes              matlab.ui.control.UIAxes
        OriginalButton      matlab.ui.control.Button
        BordesButton        matlab.ui.control.Button
        ContrasteButton     matlab.ui.control.Button
        FiltradoButton      matlab.ui.control.Button
        EntropaButton       matlab.ui.control.Button
        CargarimagenButton  matlab.ui.control.StateButton
    end


    properties (Access = private)
        Frame = uint8(zeros(360,640,3)); % Description
        initFrame = uint8(zeros(360,640,3));
        n = 1;
    end


    methods (Access = private)

        % Code that executes after component creation
        function Inicio(app)
            title(app.UIAxes, 'Original');
            app.UIFigure.Tag='on';
            app.Frame = imread('peppers.png');
            app.initFrame = app.Frame;
            imshow(app.Frame,'Parent',app.UIAxes)          
        end

        % Button pushed function: OriginalButton
        function Original(app, event)
            app.Frame = app.initFrame;
            imshow(app.Frame,'Parent',app.UIAxes)  
        end

        % Close request function: UIFigure
        function Cerrar(app, event)
            app.UIFigure.Tag='off';
            delete(app)
        end

        % Button pushed function: BordesButton
        function Bordes(app, event)
            title(app.UIAxes, 'Bordes');
            V1=zeros(size(app.Frame));
            V1(:,:,1)=edge(rgb2gray(app.Frame),'Canny').*255;
            V1(:,:,2)=V1(:,:,1);
            app.Frame=uint8(V1);
            imshow(app.Frame,'Parent',app.UIAxes)
        end

        % Button pushed function: ContrasteButton
        function Contraste(app, event)
            title(app.UIAxes, 'Laplaciano');
            app.Frame=locallapfilt(app.Frame,0.4,0.5);
            imshow(app.Frame,'Parent',app.UIAxes)
        end

        % Button pushed function: FiltradoButton
        function Filtrado(app, event)
            title(app.UIAxes, 'Filtrado');
            app.Frame=imbilatfilt(app.Frame,'degreeOfSmoothing',800,'spatialSigma',4);
            imshow(app.Frame,'Parent',app.UIAxes)
        end

        % Button pushed function: EntropaButton
        function Movimiento(app, event)
            title(app.UIAxes, 'Entropía');
            I=zeros(size(app.Frame));
            x = entropyfilt(rgb2gray(app.Frame)); %Salida double 0 a 5.66, normalizar
            x = ((x-min(x(:)))/(max(x(:))-min(x(:)))).*255;
            x = uint8(x);
            app.Frame(:,:,1) = x;
            app.Frame(:,:,2) = x;
            app.Frame(:,:,3) = x;
            imshow(app.Frame,'Parent',app.UIAxes)
        end

        % Value changed function: CargarimagenButton
        function getimage(app, event)
            value = app.CargarimagenButton.Value;
            [file,ruta]=uigetfile({'*.jpg';'*.jpeg';'*.bmp';'*.png';'*.gif';},'File Selector');
            ruta=[ruta file];
            title(app.UIAxes, 'Original');
            app.Frame = imread(ruta);
            app.initFrame = app.Frame;
            imshow(app.Frame,'Parent',app.UIAxes) 
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @Cerrar, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.OriginalButton = uibutton(app.UIFigure, 'push');
            app.OriginalButton.ButtonPushedFcn = createCallbackFcn(app, @Original, true);
            app.OriginalButton.Text = 'Original';
            app.BordesButton = uibutton(app.UIFigure, 'push');
            app.BordesButton.ButtonPushedFcn = createCallbackFcn(app, @Bordes, true);
            app.BordesButton.Text = 'Bordes';
            app.ContrasteButton = uibutton(app.UIFigure, 'push');
            app.ContrasteButton.ButtonPushedFcn = createCallbackFcn(app, @Contraste, true);
            app.ContrasteButton.Text = 'Contraste';
            app.FiltradoButton = uibutton(app.UIFigure, 'push');
            app.FiltradoButton.ButtonPushedFcn = createCallbackFcn(app, @Filtrado, true);
            app.FiltradoButton.Text = 'Filtrado';
            app.EntropaButton = uibutton(app.UIFigure, 'push');
            app.EntropaButton.ButtonPushedFcn = createCallbackFcn(app, @Movimiento, true);
            app.EntropaButton.Text = 'Entropía';
            app.CargarimagenButton = uibutton(app.UIFigure, 'state');
            app.CargarimagenButton.ValueChangedFcn = createCallbackFcn(app, @getimage, true);
            app.CargarimagenButton.Text = 'Cargar imagen';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Imgproc_Demo

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Inicio)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end