classdef Imgproc_Demo2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
        TabGroup              matlab.ui.container.TabGroup
        FiltradoTab           matlab.ui.container.Tab
        OperadorButtonGroup   matlab.ui.container.ButtonGroup
        PromedioButton        matlab.ui.control.RadioButton
        MedianaButton         matlab.ui.control.RadioButton
        GaussianoButton       matlab.ui.control.RadioButton
        BilateralButton       matlab.ui.control.RadioButton
        VentanaSliderLabel    matlab.ui.control.Label
        VentanaSlider         matlab.ui.control.Slider
        sigma_dSpinnerLabel   matlab.ui.control.Label
        sigma_dSpinner        matlab.ui.control.Spinner
        sigma_rSpinnerLabel   matlab.ui.control.Label
        sigma_rSpinner        matlab.ui.control.Spinner
        AplicarcambiosButton  matlab.ui.control.Button
        GammaTab              matlab.ui.container.Tab
        CargarimagenButton    matlab.ui.control.Button
        ResetButton           matlab.ui.control.Button
    end


    properties (Access = private)
        Frame = uint8(zeros(360,640,3)); % Description
        initFrame = uint8(zeros(360,640,3));
        n = 1;
    end


    methods (Access = private)

        % Code that executes after component creation
        function Inicio(app)
            title(app.UIAxes, 'Original');
            app.UIFigure.Tag='on';
            app.Frame = imread('peppers.png');
            app.initFrame = app.Frame;
            imshow(app.initFrame,'Parent',app.UIAxes)  
            imshow(app.Frame,'Parent',app.UIAxes_2)
        end

        % Button pushed function: CargarimagenButton
        function getImage(app, event)
            [file,ruta] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.gif;*.tif',...
                'Image Files (*.jpg,*.jpeg,*.png,*.bmp,*.gif,*.tiff)'},'Seleccione una imagen');
            if file~=0
                ruta=[ruta file];
                title(app.UIAxes, 'Original');
                app.Frame = imread(ruta);
                app.initFrame = app.Frame;
                imshow(app.Frame,'Parent',app.UIAxes)
            end
        end

        % Button pushed function: ResetButton
        function Resetimage(app, event)
            app.Frame = app.initFrame;
            imshow(app.Frame,'Parent',app.UIAxes_2)
        end

        % Selection changed function: OperadorButtonGroup
        function FiltroSel(app, event)
            selectedButton = app.OperadorButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Promedio'
                    WindowSize = app.VentanaSlider.Value;
                    app.sigma_dSpinner.Enable = 'off';
                    app.sigma_rSpinner.Enable = 'off';
                case 'Mediana'
                    WindowSize = app.VentanaSlider.Value;
                    app.sigma_dSpinner.Enable = 'off';
                    app.sigma_rSpinner.Enable = 'off';
                case 'Gaussiano'
                    WindowSize = app.VentanaSlider.Value;
                    app.sigma_dSpinner.Enable = 'on';
                    app.sigma_rSpinner.Enable = 'off';
                case 'Bilateral'
                    WindowSize = app.VentanaSlider.Value;
                    app.sigma_dSpinner.Enable = 'on';
                    app.sigma_rSpinner.Enable = 'on';
                otherwise
                    WindowSize = app.VentanaSlider.Value;
                    app.sigma_dSpinner.Enable = 'on';
                    app.sigma_rSpinner.Enable = 'on';
            end
        end

        % Value changed function: VentanaSlider
        function IntegersOnly(app, event)
            value = app.VentanaSlider.Value;
            value = round(value);
            if ~mod(value,2) %Revisamos si es par, en ese caso sumamos 1
                value = value+1;
            end
            app.VentanaSlider.Value = value;
        end

        % Button pushed function: AplicarcambiosButton
        function AplicarFiltro(app, event)
            selectedButton = app.OperadorButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Promedio'
                    WindowSize = app.VentanaSlider.Value;
                    
                    % Encapsulamiento y se evita combinar código de GUI y procesamiento
                    kernel = ones(WindowSize)./(WindowSize.^2);
                    R=app.Frame(:,:,1);
                    G=app.Frame(:,:,2);
                    B=app.Frame(:,:,3);
                    R = uint8(conv2(R,kernel,'same'));
                    G = uint8(conv2(G,kernel,'same'));
                    B = uint8(conv2(B,kernel,'same'));
                    app.Frame(:,:,1) = R;
                    app.Frame(:,:,2) = G;
                    app.Frame(:,:,3) = B;
                    % Estas líneas deberían reducirse a 1 sola función
                    
                    imshow(app.Frame,'Parent',app.UIAxes_2)
                case 'Mediana'
                    WindowSize = app.VentanaSlider.Value;
                    
                    R=app.Frame(:,:,1);
                    G=app.Frame(:,:,2);
                    B=app.Frame(:,:,3);
                    R = uint8(medfilt2(R,[WindowSize WindowSize]));
                    G = uint8(medfilt2(G,[WindowSize WindowSize]));
                    B = uint8(medfilt2(B,[WindowSize WindowSize]));
                    app.Frame(:,:,1) = R;
                    app.Frame(:,:,2) = G;
                    app.Frame(:,:,3) = B;
                    
                    imshow(app.Frame,'Parent',app.UIAxes_2)
                case 'Gaussiano'
                    WindowSize = app.VentanaSlider.Value;
                    value = app.sigma_dSpinner.Value;
                    % Forma con encapsulado, permite separar Front End de Back End
                    app.Frame = imgaussfilt(app.Frame,value);
                    
                    imshow(app.Frame,'Parent',app.UIAxes_2)
                case 'Bilateral'
                    WindowSize = app.VentanaSlider.Value;
                    value = app.sigma_dSpinner.Value;
                    value2 = app.sigma_rSpinner.Value;
                    % Forma con encapsulado, permite separar Front End de Back End
                    app.Frame = im2double(app.Frame);
                    app.Frame = imbilatfilt(app.Frame,value,value2,'NeighborhoodSize',WindowSize);
                    app.Frame = im2uint8(app.Frame);
                    imshow(app.Frame,'Parent',app.UIAxes_2)
                otherwise
                    WindowSize = app.VentanaSlider.Value;
                    app.sigma_dSpinner.Enable = 'on';
                    app.sigma_rSpinner.Enable = 'on';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Procesada')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FiltradoTab = uitab(app.TabGroup);
            app.FiltradoTab.Title = 'Filtrado';
            app.OperadorButtonGroup = uibuttongroup(app.FiltradoTab);
            app.OperadorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FiltroSel, true);
            app.OperadorButtonGroup.Title = 'Operador';
            app.PromedioButton = uiradiobutton(app.OperadorButtonGroup);
            app.PromedioButton.Text = 'Promedio';
            app.PromedioButton.Value = true;
            app.MedianaButton = uiradiobutton(app.OperadorButtonGroup);
            app.MedianaButton.Text = 'Mediana';
            app.GaussianoButton = uiradiobutton(app.OperadorButtonGroup);
            app.GaussianoButton.Text = 'Gaussiano';
            app.BilateralButton = uiradiobutton(app.OperadorButtonGroup);
            app.BilateralButton.Text = 'Bilateral';
            app.VentanaSliderLabel = uilabel(app.FiltradoTab);
            app.VentanaSliderLabel.Text = 'Ventana';
            app.VentanaSlider = uislider(app.FiltradoTab);
            app.VentanaSlider.Limits = [3 31];
            app.VentanaSlider.ValueChangedFcn = createCallbackFcn(app, @IntegersOnly, true);
            app.VentanaSlider.MinorTicks = [];
            app.VentanaSlider.Value = 3;
            app.sigma_dSpinnerLabel = uilabel(app.FiltradoTab);
            app.sigma_dSpinnerLabel.Text = 'sigma_d';
            app.sigma_dSpinner = uispinner(app.FiltradoTab);
            app.sigma_dSpinner.Step = 0.5;
            app.sigma_dSpinner.Limits = [0 40];
            app.sigma_dSpinner.Value = 0.5;
            app.sigma_rSpinnerLabel = uilabel(app.FiltradoTab);
            app.sigma_rSpinnerLabel.Text = 'sigma_r';
            app.sigma_rSpinner = uispinner(app.FiltradoTab);
            app.sigma_rSpinner.Step = 0.5;
            app.sigma_rSpinner.Limits = [0 40];
            app.sigma_rSpinner.Value = 0.1;
            app.AplicarcambiosButton = uibutton(app.FiltradoTab, 'push');
            app.AplicarcambiosButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarFiltro, true);
            app.AplicarcambiosButton.Text = 'Aplicar cambios';
            app.GammaTab = uitab(app.TabGroup);
            app.GammaTab.Title = 'Gamma';
            app.CargarimagenButton = uibutton(app.UIFigure, 'push');
            app.CargarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @getImage, true);
            app.CargarimagenButton.Text = 'Cargar imagen';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @Resetimage, true);
            app.ResetButton.Text = 'Reset';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Imgproc_Demo2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Inicio)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end