classdef Webcam_Demo2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        OriginalButton    matlab.ui.control.Button
        BordesButton      matlab.ui.control.Button
        ContrasteButton   matlab.ui.control.Button
        FiltradoButton    matlab.ui.control.Button
        MovimientoButton  matlab.ui.control.Button
    end


    properties (Access = private)
        Frame = uint8(zeros(360,640,3)); % Description
        PrevFrame = uint8(zeros(360,640,3));
        n = 1;
    end


    methods (Access = private)

        % Code that executes after component creation
        function Inicio(app)
            app.UIFigure.UserData=webcam;
            app.UIFigure.UserData.Resolution='640x360';
            title(app.UIAxes, ' ');
            app.UIFigure.Tag='on';
            pause(5);
            F = snapshot(app.UIFigure.UserData);
            app.Frame=uint8(zeros(size(F)));
            while isequal(app.UIFigure.Tag,'on')
                switch app.n
                    case 1
                        title(app.UIAxes, 'Original');
                        app.Frame = snapshot(app.UIFigure.UserData);
                    case 2
                        app.Frame = snapshot(app.UIFigure.UserData);
                        title(app.UIAxes, 'Bordes');
                        V1=zeros(size(app.Frame));
                        V1(:,:,1)=edge(rgb2gray(app.Frame),'Canny').*255;
                        V1(:,:,2)=V1(:,:,1);
                        app.Frame=uint8(V1);
                    case 3
                        title(app.UIAxes, 'Contraste');
                        app.Frame = snapshot(app.UIFigure.UserData);
                        app.Frame=locallapfilt(app.Frame,0.4,0.5);
                    case 4
                        title(app.UIAxes, 'Entropia');
                        app.Frame = snapshot(app.UIFigure.UserData);
                        app.Frame=imbilatfilt(app.Frame,'degreeOfSmoothing',800,'spatialSigma',4);
%                         I=zeros(size(app.Frame));
%                         I(:,:,1) = entropyfilt(rgb2gray(app.Frame));
%                         I(:,:,2) = I(:,:,1);
%                         I(:,:,3) = I(:,:,2);
%                         app.Frame = I;
                    case 5
                        title(app.UIAxes, 'Movimiento');
                        videoFrame = snapshot(app.UIFigure.UserData);
                        app.Frame=imabsdiff(app.PrevFrame,videoFrame);
                        app.PrevFrame=videoFrame;
                    otherwise
                        title(app.UIAxes, 'Original');
                        app.Frame = snapshot(app.UIFigure.UserData);
                end
           
            if isequal(app.UIFigure.Tag,'off')
                delete(app)
                break
            end
            
            imshow(app.Frame,'Parent',app.UIAxes)
            
            end
        end

        % Button pushed function: OriginalButton
        function Original(app, event)
            app.n=1;
        end

        % Close request function: UIFigure
        function Cerrar(app, event)
            app.UIFigure.Tag='off';
            delete(app)
        end

        % Button pushed function: BordesButton
        function Bordes(app, event)
            app.n=2;
        end

        % Button pushed function: ContrasteButton
        function Contraste(app, event)
            app.n=3;
        end

        % Button pushed function: FiltradoButton
        function Filtrado(app, event)
            app.n=4;
        end

        % Button pushed function: MovimientoButton
        function Movimiento(app, event)
            app.n=5;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @Cerrar, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Webcam')
            app.OriginalButton = uibutton(app.UIFigure, 'push');
            app.OriginalButton.ButtonPushedFcn = createCallbackFcn(app, @Original, true);
            app.OriginalButton.Text = 'Original';
            app.BordesButton = uibutton(app.UIFigure, 'push');
            app.BordesButton.ButtonPushedFcn = createCallbackFcn(app, @Bordes, true);
            app.BordesButton.Text = 'Bordes';
            app.ContrasteButton = uibutton(app.UIFigure, 'push');
            app.ContrasteButton.ButtonPushedFcn = createCallbackFcn(app, @Contraste, true);
            app.ContrasteButton.Text = 'Contraste';
            app.FiltradoButton = uibutton(app.UIFigure, 'push');
            app.FiltradoButton.ButtonPushedFcn = createCallbackFcn(app, @Filtrado, true);
            app.FiltradoButton.Text = 'Filtrado';
            app.MovimientoButton = uibutton(app.UIFigure, 'push');
            app.MovimientoButton.ButtonPushedFcn = createCallbackFcn(app, @Movimiento, true);
            app.MovimientoButton.Text = 'Movimiento';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Webcam_Demo2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Inicio)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end